classdef interfaccia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        UnaceramicaTab               matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        PiezoelettricoPanel          matlab.ui.container.Panel
        DensitaDiMassaValue          matlab.ui.control.Label
        CostantePiezoelettricaValue  matlab.ui.control.Label
        CostantehValue               matlab.ui.control.Label
        CostanteElasticaValue        matlab.ui.control.Label
        DensitaDiMassaLabel          matlab.ui.control.Label
        CostantePiezoelettricaLabel  matlab.ui.control.Label
        CostantehLabel               matlab.ui.control.Label
        CostanteElasticaLabel        matlab.ui.control.Label
        PiezoelettricoDropDown       matlab.ui.control.DropDown
        DueCeramicheTab              matlab.ui.container.Tab
        TrasduttorebandalargaTab     matlab.ui.container.Tab
        LangevinTab                  matlab.ui.container.Tab
        LangevinconconcentratoreTab  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        piezoTable
    end
    
    methods (Access = private)
        
        function aggiornaPiezo(app, piezoelettrico)
            app.CostanteElasticaValue.Text = num2str(app.piezoTable(56, piezoelettrico).(1), '%.3g');
            app.CostantehValue.Text = num2str(app.piezoTable(23, piezoelettrico).(1), '%.3g');
            app.CostantePiezoelettricaValue.Text = num2str(app.piezoTable(20, piezoelettrico).(1), '%.3g');
            app.DensitaDiMassaValue.Text = num2str(app.piezoTable(33, piezoelettrico).(1), '%.3g');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('../condivise');
            app.piezoTable = readtable('Ferroperm MatData.xls');
            properties = app.piezoTable.Properties.VariableNames(3:end);
            app.PiezoelettricoDropDown.Items = properties;
            aggiornaPiezo(app, 'Pz21');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UnaceramicaTab = uitab(app.TabGroup);
            app.UnaceramicaTab.Title = 'Una ceramica';
            app.Panel = uipanel(app.UnaceramicaTab);
            app.PiezoelettricoPanel = uipanel(app.Panel);
            app.PiezoelettricoPanel.Title = 'Piezoelettrico';
            app.PiezoelettricoDropDown = uidropdown(app.PiezoelettricoPanel);
            app.CostanteElasticaLabel = uilabel(app.PiezoelettricoPanel);
            app.CostanteElasticaLabel.Text = 'Costante elastica:';
            app.CostantehLabel = uilabel(app.PiezoelettricoPanel);
            app.CostantehLabel.Text = 'Costante h:';
            app.CostantePiezoelettricaLabel = uilabel(app.PiezoelettricoPanel);
            app.CostantePiezoelettricaLabel.Text = 'Costante piezoelettrica:';
            app.DensitaDiMassaLabel = uilabel(app.PiezoelettricoPanel);
            app.DensitaDiMassaLabel.Text = 'Densità di massa :';
            app.CostanteElasticaValue = uilabel(app.PiezoelettricoPanel);
            app.CostanteElasticaValue.Text = '';
            app.CostantehValue = uilabel(app.PiezoelettricoPanel);
            app.CostantehValue.Text = '';
            app.CostantePiezoelettricaValue = uilabel(app.PiezoelettricoPanel);
            app.CostantePiezoelettricaValue.Text = '';
            app.DensitaDiMassaValue = uilabel(app.PiezoelettricoPanel);
            app.DensitaDiMassaValue.Text = '';
            app.DueCeramicheTab = uitab(app.TabGroup);
            app.DueCeramicheTab.Title = 'Due Ceramiche';
            app.TrasduttorebandalargaTab = uitab(app.TabGroup);
            app.TrasduttorebandalargaTab.Title = 'Trasduttore banda larga';
            app.LangevinTab = uitab(app.TabGroup);
            app.LangevinTab.Title = 'Langevin';
            app.LangevinconconcentratoreTab = uitab(app.TabGroup);
            app.LangevinconconcentratoreTab.Title = 'Langevin con concentratore';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaccia

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end