classdef interfaccia_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IntervalloconsideratoPanel      matlab.ui.container.Panel
        FattoremoltiplicativosuperioreEditField  matlab.ui.control.NumericEditField
        FattoremoltiplicativosuperioreLabel  matlab.ui.control.Label
        FattoremoltiplicativoinferioreEditField  matlab.ui.control.NumericEditField
        FattoremoltiplicativoinferioreEditFieldLabel  matlab.ui.control.Label
        NumeroCampioniEditField         matlab.ui.control.NumericEditField
        NumeroCampioniEditFieldLabel    matlab.ui.control.Label
        ValoriDerivatiPanel             matlab.ui.container.Panel
        FrequenzaDiRisonanzaValue       matlab.ui.control.Label
        ImpedenzaAcusticaDirezionezValue  matlab.ui.control.Label
        ImpedenzaAcusticaPiezoValue     matlab.ui.control.Label
        VelocitaDiPropagazioneValue     matlab.ui.control.Label
        CapacitaStaticaValue            matlab.ui.control.Label
        AreapiezoValue                  matlab.ui.control.Label
        CostantebetaValue               matlab.ui.control.Label
        FrequenzadirisonanzaHzLabel     matlab.ui.control.Label
        ImpedenzaacusticadellelementoindirezionezkgsLabel  matlab.ui.control.Label
        Impedenzaacusticapiezokgm2sLabel  matlab.ui.control.Label
        VelocitdipropagazionemsLabel    matlab.ui.control.Label
        CapacitstaticaCVLabel           matlab.ui.control.Label
        Areapiezoelettricom2Label       matlab.ui.control.Label
        CostantebetaVmCLabel            matlab.ui.control.Label
        GeometriadellapiastraPanel      matlab.ui.container.Panel
        SpessoremEditField              matlab.ui.control.NumericEditField
        SpessoremLabel                  matlab.ui.control.Label
        LarghezzamEditField             matlab.ui.control.NumericEditField
        LarghezzamEditFieldLabel        matlab.ui.control.Label
        LunghezzamEditField             matlab.ui.control.NumericEditField
        LunghezzamEditFieldLabel        matlab.ui.control.Label
        SceltaPiezoelettricoPanel       matlab.ui.container.Panel
        DensitaDiMassaValue             matlab.ui.control.Label
        CostantePiezoelettricaValue     matlab.ui.control.Label
        CostantehValue                  matlab.ui.control.Label
        CostanteElasticaValue           matlab.ui.control.Label
        DensitaDiMassaLabel             matlab.ui.control.Label
        CostantePiezoelettricaLabel     matlab.ui.control.Label
        CostantehVmLabel                matlab.ui.control.Label
        CostanteElasticaLabel           matlab.ui.control.Label
        PiezoelettricoDropDown          matlab.ui.control.DropDown
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        UnaceramicaTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        StudiosingolaconfigurazioneTab  matlab.ui.container.Tab
        CondizionialcontornoPanel       matlab.ui.container.Panel
        StudiaButton1_1                 matlab.ui.control.Button
        PerditemeccanicheCheckBox       matlab.ui.control.CheckBox
        Terminale2DropDown              matlab.ui.control.DropDown
        Terminale2DropDownLabel         matlab.ui.control.Label
        Terminale1DropDown              matlab.ui.control.DropDown
        Terminale1Label                 matlab.ui.control.Label
        UIAxes1_1_3                     matlab.ui.control.UIAxes
        UIAxes1_1_2                     matlab.ui.control.UIAxes
        UIAxes1_1_1                     matlab.ui.control.UIAxes
        ConfrontodueconfigurazioniTab   matlab.ui.container.Tab
        CondizionialcontornoPanel_2     matlab.ui.container.Panel
        StudiaButton1_2                 matlab.ui.control.Button
        PerditemeccanicheCheckBox_1_2_2  matlab.ui.control.CheckBox
        TerminaleDropDown_1_2_2         matlab.ui.control.DropDown
        Terminale2DropDown_2Label       matlab.ui.control.Label
        TerminaleDropDown_1_2_1         matlab.ui.control.DropDown
        Terminale1DropDown_2Label       matlab.ui.control.Label
        PerditemeccanicheCheckBox_1_2_1  matlab.ui.control.CheckBox
        Configurazione2Label            matlab.ui.control.Label
        TerminaleDropDown_1_1_2         matlab.ui.control.DropDown
        Terminale2Label                 matlab.ui.control.Label
        TerminaleDropDown_1_1_1         matlab.ui.control.DropDown
        Terminale1Label_2               matlab.ui.control.Label
        Configurazione1Label            matlab.ui.control.Label
        UIAxes1_2_3                     matlab.ui.control.UIAxes
        UIAxes1_2_2                     matlab.ui.control.UIAxes
        UIAxes1_2_1                     matlab.ui.control.UIAxes
        DueCeramicheTab                 matlab.ui.container.Tab
        CondizionialcontornoPanel_3     matlab.ui.container.Panel
        StudiaButton2_1                 matlab.ui.control.Button
        PerditemeccanicheCheckBox_2_1   matlab.ui.control.CheckBox
        TerminaleDropdown_2_2           matlab.ui.control.DropDown
        Terminale2DropDown_2Label_2     matlab.ui.control.Label
        TerminaleDropdown_2_1           matlab.ui.control.DropDown
        Terminale1DropDown_2Label_2     matlab.ui.control.Label
        UIAxes2_1_3                     matlab.ui.control.UIAxes
        UIAxes2_1_2                     matlab.ui.control.UIAxes
        UIAxes2_1_1                     matlab.ui.control.UIAxes
        TrasduttorebandalargaTab        matlab.ui.container.Tab
        CondizionialcontornoPanel_4     matlab.ui.container.Panel
        CalcolaspessoreformuleButton_2  matlab.ui.control.Button
        CalcolaspessoreformuleButton_1  matlab.ui.control.Button
        CalcolaspessoremiglioreButton_2  matlab.ui.control.Button
        CalcolaspessoremiglioreButton_1  matlab.ui.control.Button
        SpessorepiastradiadattamentoEditField_2  matlab.ui.control.NumericEditField
        SpessorepiastradiadattamentoEditField_2Label  matlab.ui.control.Label
        SpessorepiastradiadattamentoEditField_1  matlab.ui.control.NumericEditField
        SpessorepiastradiadattamentoEditFieldLabel  matlab.ui.control.Label
        StudiaButton3_1                 matlab.ui.control.Button
        PerditemeccanicheCheckBox_3_1_2  matlab.ui.control.CheckBox
        TerminaleDropDown_3_1_2_2       matlab.ui.control.DropDown
        Terminale2DropDown_2Label_3     matlab.ui.control.Label
        TerminaleDropDown_3_1_2_1       matlab.ui.control.DropDown
        Terminale1DropDown_2Label_3     matlab.ui.control.Label
        PerditemeccanicheCheckBox_3_1_1  matlab.ui.control.CheckBox
        Configurazione2Label_2          matlab.ui.control.Label
        TerminaleDropDown_3_1_1_2       matlab.ui.control.DropDown
        Terminale2Label_2               matlab.ui.control.Label
        TerminaleDropDown_3_1_1_1       matlab.ui.control.DropDown
        Terminale1Label_3               matlab.ui.control.Label
        Configurazione1Label_2          matlab.ui.control.Label
        UIAxes3_1_3                     matlab.ui.control.UIAxes
        UIAxes3_1_2                     matlab.ui.control.UIAxes
        UIAxes3_1_1                     matlab.ui.control.UIAxes
        LangevinTab                     matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        StudiaButton4_1                 matlab.ui.control.Button
        FrequenzadilavoroKHzEditField   matlab.ui.control.NumericEditField
        FrequenzadilavoroKHzEditFieldLabel  matlab.ui.control.Label
        ConfigurazioneDropDown          matlab.ui.control.DropDown
        ConfigurazioneDropDownLabel     matlab.ui.control.Label
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes4_1                       matlab.ui.control.UIAxes
        LangevinconconcentratoreTab     matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        StudiaButton5_1                 matlab.ui.control.Button
        FrequenzadilavoroKHzEditField_2  matlab.ui.control.NumericEditField
        FrequenzadilavoroKHzEditField_2Label  matlab.ui.control.Label
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5_1                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        piezoTable = readtable('Ferroperm MatData.xls');
        rho_acqua = 997;
        v_acqua = 1484;
        rho_aria = 1.225;
        v_aria = 343;
        rho_titanio = 4.507 * 10^3;
        v_titanio = 6100; 
        z_backing = 7e6;
        z_aria;
        z_acqua;
        Zel = 1e7;
        perdite_meccaniche = 0.1e6;

        h_33;
        c_33_D;
        beta_33_S;
        e_33;
        rho_ceramica;
        v_ceramica;
        z_ceramica;
        areaPiezo;
        C_0;
        Z_0_D;
        f_r;
    end
    
    methods (Access = private)

        function initImpedenzeMateriali(app) 
            app.z_acqua = app.rho_acqua * app.v_acqua; 
            app.z_aria = app.rho_aria * app.v_aria; 
        end
        
        function aggiornaPiezo(app, piezoelettrico)
            app.CostanteElasticaValue.Text = strcat(num2str(app.piezoTable(56, piezoelettrico).(1), '%.3g'), ' ');          
            app.c_33_D = str2double(app.CostanteElasticaValue.Text);
            app.CostantehValue.Text = strcat(num2str(app.piezoTable(23, piezoelettrico).(1), '%.3g'), ' ' );
            app.h_33 = str2double(app.CostantehValue.Text);
            app.CostantePiezoelettricaValue.Text = strcat(num2str(app.piezoTable(20, piezoelettrico).(1), '%.3g'), ' ');
            app.e_33 = str2double(app.CostantePiezoelettricaValue.Text);
            app.DensitaDiMassaValue.Text = strcat(num2str(app.piezoTable(33, piezoelettrico).(1), '%.3g'), ' ');
            app.rho_ceramica = str2double(app.DensitaDiMassaValue.Text);
        end

        function calcolaValoriDerivati(app)
            app.beta_33_S = str2double(app.CostantehValue.Text) / str2double(app.CostantePiezoelettricaValue.Text);
            app.CostantebetaValue.Text = strcat(num2str(app.beta_33_S, '%.3g'), ' ');

            app.areaPiezo = app.LarghezzamEditField.Value * app.LunghezzamEditField.Value;
            app.AreapiezoValue.Text = strcat(num2str(app.areaPiezo, '%.2e'), ' ');

            app.C_0 = str2double(app.AreapiezoValue.Text)/(str2double(app.CostantebetaValue.Text) * app.SpessoremEditField.Value);
            app.CapacitaStaticaValue.Text = strcat(num2str(app.C_0, '%.3g'), ' ');

            app.v_ceramica = sqrt(str2double(app.CostanteElasticaValue.Text) / str2double(app.DensitaDiMassaValue.Text));
            app.VelocitaDiPropagazioneValue.Text = strcat(num2str(app.v_ceramica, '%.3g'), ' ');

            app.z_ceramica = str2double(app.VelocitaDiPropagazioneValue.Text) * str2double(app.DensitaDiMassaValue.Text);
            app.ImpedenzaAcusticaPiezoValue.Text = strcat(num2str(app.z_ceramica , '%.3g'), ' ');

            app.Z_0_D = str2double(app.AreapiezoValue.Text) * str2double(app.ImpedenzaAcusticaPiezoValue.Text);
            app.ImpedenzaAcusticaDirezionezValue.Text = strcat(num2str(app.Z_0_D , '%.3g'), ' ');

            app.f_r = str2double(app.VelocitaDiPropagazioneValue.Text)/(2 * app.SpessoremEditField.Value);
            app.FrequenzaDiRisonanzaValue.Text = strcat(num2str(app.f_r, '%.3g'), ' ');
           
        end

        function [z] = getZDaConfigurazione(app, materiale, isPerdite, areaPiezo)
            z = 0;
            if(materiale == "Acqua")
                z = app.z_acqua;
            end
            if(materiale == "Aria")
                z = app.z_aria;
            end
            if(materiale == "Backing")
                z = app.z_backing;
            end
            if(isPerdite)
                z = (z + app.perdite_meccaniche) * areaPiezo;
            else
                z = z * areaPiezo;
            end
        end

        function [freq_vector] = getFreqVector(app)
            N_Campioni = app.NumeroCampioniEditField.Value;
            f_low = app.FattoremoltiplicativoinferioreEditField.Value * app.f_r;
            f_high = app.FattoremoltiplicativosuperioreEditField.Value * app.f_r;
            freq_vector = linspace(f_low, f_high, N_Campioni);
        end

        function stampaGrafico(app, figureSelected, x, y, titolo, labelx, labely)
            plot(figureSelected,x, y);
            grid(figureSelected, true);
            title(figureSelected, titolo)
            xlabel(figureSelected, labelx)
            ylabel(figureSelected, labely)
           % set(figureSelected,'xtick',[0:0.1:10])
        end

        function stampaGraficiMultipli(app, figureSelected, x1, y1, legend1, x2, y2, legend2, titolo, labelx, labely)
            plot(figureSelected,x1, y1);
            hold(figureSelected, "on");
            plot(figureSelected,x2, y2);
            grid(figureSelected, true);
            title(figureSelected, titolo);
            xlabel(figureSelected, labelx);
            ylabel(figureSelected, labely);
            legend(figureSelected,legend1, legend2);
            hold(figureSelected, "off");
           % set(figureSelected,'xtick',[0:0.1:10])
        end

        function [f0] = getf0BLfromConfiguration(app, Z1, Z2)
            freq_vector = app.getFreqVector();
            spessore = app.SpessoremEditField.Value;

            Zin = calcolaZin(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, Z2, true);
            [Zin, indexMin] = min(abs(Zin));
            f0 = freq_vector(indexMin); 

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initImpedenzeMateriali(app);
            addpath('../condivise');
            addpath('../3 Trasduttore a banda larga');
            addpath('../4 Trasduttore Langevin');
            addpath('../5 Trasduttore Langevin con concentratore');
            properties = app.piezoTable.Properties.VariableNames;
            propertiesConsidered = [properties(3:10), properties(15:end)];
            app.PiezoelettricoDropDown.Items = propertiesConsidered;
            aggiornaPiezo(app, 'Pz21');
            calcolaValoriDerivati(app);
        end

        % Value changed function: PiezoelettricoDropDown
        function PiezoelettricoDropDownValueChanged(app, event)
            value = app.PiezoelettricoDropDown.Value;
            aggiornaPiezo(app, value);
            calcolaValoriDerivati(app);
        end

        % Value changed function: SpessoremEditField
        function SpessoremEditFieldValueChanged(app, event)
            calcolaValoriDerivati(app);
        end

        % Value changed function: LarghezzamEditField
        function LarghezzamEditFieldValueChanged(app, event)
            calcolaValoriDerivati(app);
        end

        % Value changed function: LunghezzamEditField
        function LunghezzamEditFieldValueChanged(app, event)
            calcolaValoriDerivati(app);
        end

        % Button pushed function: StudiaButton1_1
        function StudiaButton1_1Pushed(app, event)
            freq_vector = app.getFreqVector();
            isPerdite = app.PerditemeccanicheCheckBox.Value;
            spessore = app.SpessoremEditField.Value;
            Z1 = app.getZDaConfigurazione(app.Terminale1DropDown.Value, isPerdite, app.areaPiezo);
            Z2 = app.getZDaConfigurazione(app.Terminale2DropDown.Value, isPerdite, app.areaPiezo);

            %disp(app.Z_0_D)

            Zin = calcolaZin(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, Z2, true);
            FTT = calcolaFTT(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, Z2, true);
            FTR = calcolaFTR(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, app.Zel, true);


            app.stampaGrafico(app.UIAxes1_1_1, freq_vector./1e6, mag2db(abs(Zin)), 'Impedenza', 'frequenza [MHz]', 'Zin [Ohm]');
            app.stampaGrafico(app.UIAxes1_1_2, freq_vector./1e6, mag2db(abs(FTT)), 'Funzione di trasferimento trasmissione', 'frequenza [MHz]', 'FTT [dB]');
            app.stampaGrafico(app.UIAxes1_1_3, freq_vector./1e6, mag2db(abs(FTR)), 'Funzione di trasferimento ricezione', 'frequenza [MHz]', 'FTR [dB]');
        end

        % Button pushed function: StudiaButton1_2
        function StudiaButton1_2Pushed(app, event)
            freq_vector = app.getFreqVector();
            isPerdite_1 = app.PerditemeccanicheCheckBox_1_2_1.Value;
            isPerdite_2 = app.PerditemeccanicheCheckBox_1_2_2.Value;
            spessore = app.SpessoremEditField.Value;

            Z1_1 = app.getZDaConfigurazione(app.TerminaleDropDown_1_1_1.Value, isPerdite_1, app.areaPiezo);
            Z2_1 = app.getZDaConfigurazione(app.TerminaleDropDown_1_1_2.Value, isPerdite_1, app.areaPiezo);
            Z1_2 = app.getZDaConfigurazione(app.TerminaleDropDown_1_2_1.Value, isPerdite_2, app.areaPiezo);
            Z2_2 = app.getZDaConfigurazione(app.TerminaleDropDown_1_2_2.Value, isPerdite_2, app.areaPiezo);

            Zin_1 = calcolaZin(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1_1, Z2_1, true);
            FTT_1 = calcolaFTT(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1_1, Z2_1, true);
            FTR_1 = calcolaFTR(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1_1, app.Zel, true);
            Zin_2 = calcolaZin(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1_2, Z2_2, true);
            FTT_2 = calcolaFTT(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1_2, Z2_2, true);
            FTR_2 = calcolaFTR(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1_2, app.Zel, true);

            app.stampaGraficiMultipli(app.UIAxes1_2_1, freq_vector./1e6, mag2db(abs(Zin_1)), 'Configurazione 1',freq_vector./1e6, mag2db(abs(Zin_2)), 'Configurazione 2','Impedenza', 'frequenza [MHz]', 'Zin [Ohm]')
            app.stampaGraficiMultipli(app.UIAxes1_2_2, freq_vector./1e6, mag2db(abs(FTT_1)), 'Configurazione 1',freq_vector./1e6, mag2db(abs(FTT_2)), 'Configurazione 2','Funzione di trasferimento trasmissione', 'frequenza [MHz]', 'FTT [dB]')
            app.stampaGraficiMultipli(app.UIAxes1_2_3, freq_vector./1e6, mag2db(abs(FTR_1)), 'Configurazione 1',freq_vector./1e6, mag2db(abs(FTR_2)), 'Configurazione 2','Funzione di trasferimento ricezione', 'frequenza [MHz]', 'FTR [dB]')
        end

        % Button pushed function: StudiaButton2_1
        function StudiaButton2_1Pushed(app, event)
            freq_vector = app.getFreqVector();
            isPerdite = app.PerditemeccanicheCheckBox_2_1.Value;
            spessore = app.SpessoremEditField.Value;
            Z1 = app.getZDaConfigurazione(app.TerminaleDropdown_2_1.Value, isPerdite, app.areaPiezo);
            Z2 = app.getZDaConfigurazione(app.TerminaleDropdown_2_2.Value, isPerdite, app.areaPiezo);

            %disp(app.Z_0_D)

            Zin = calcolaZin(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, Z2, true);
            FTT = calcolaFTT(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, Z2, true);
            FTR = calcolaFTR(app.Z_0_D, freq_vector, app.v_ceramica, spessore, app.h_33, app.C_0, Z1, app.Zel, true);

            spessore_half = spessore/2;
            C_0_half = app.areaPiezo / (app.beta_33_S * spessore_half);


            Zin_DC = calcolaZin(app.Z_0_D, freq_vector, app.v_ceramica, spessore_half, app.h_33, C_0_half, Z1, Z2, false);
            FTT_DC = calcolaFTT(app.Z_0_D, freq_vector, app.v_ceramica, spessore_half, app.h_33, C_0_half, Z1, Z2, false);
            FTR_DC = calcolaFTR(app.Z_0_D, freq_vector, app.v_ceramica, spessore_half, app.h_33, C_0_half, Z1, app.Zel, false);

            app.stampaGraficiMultipli(app.UIAxes2_1_1, freq_vector./1e6, mag2db(abs(Zin)), 'Una ceramica',freq_vector./1e6, mag2db(abs(Zin_DC)), 'Due ceramiche','Impedenza', 'frequenza [MHz]', 'Zin [Ohm]')
            app.stampaGraficiMultipli(app.UIAxes2_1_2, freq_vector./1e6, mag2db(abs(FTT)), 'Una ceramica',freq_vector./1e6, mag2db(abs(FTT_DC)), 'Due ceramiche','Funzione di trasferimento trasmissione', 'frequenza [MHz]', 'FTT [dB]')
            app.stampaGraficiMultipli(app.UIAxes2_1_3, freq_vector./1e6, mag2db(abs(FTR)), 'Una ceramica',freq_vector./1e6, mag2db(abs(FTR_DC)), 'Due ceramiche','Funzione di trasferimento ricezione', 'frequenza [MHz]', 'FTR [dB]')
        end

        % Button pushed function: CalcolaspessoreformuleButton_1
        function CalcolaspessoreformuleButton_1Pushed(app, event)
            freq_vector = app.getFreqVector();
            spessore = app.SpessoremEditField.Value;
            isPerdite = app.PerditemeccanicheCheckBox_3_1_1.Value;
            Z1 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_1_1.Value, isPerdite, app.areaPiezo);
            Z2 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_1_2.Value, isPerdite, app.areaPiezo);

            f0 = getf0BLfromConfiguration(app, Z1, Z2);
            
            [Zintrasduttore,FTTtrasduttore, spessorePiastraIniziale] = simulaTrasduttoreBandaLarga(freq_vector, f0, app.areaPiezo, app.v_ceramica, app.rho_ceramica, app.z_acqua, app.rho_acqua, Z1, Z2, app.C_0, spessore, app.h_33, 0);
            
            app.SpessorepiastradiadattamentoEditField_1.Value = spessorePiastraIniziale;
        end

        % Button pushed function: CalcolaspessoremiglioreButton_2
        function CalcolaspessoremiglioreButton_2Pushed(app, event)
            freq_vector = app.getFreqVector();
            spessore = app.SpessoremEditField.Value;
            isPerdite = app.PerditemeccanicheCheckBox_3_1_2.Value;
            Z1 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_2_1.Value, isPerdite, app.areaPiezo);
            Z2 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_2_2.Value, isPerdite, app.areaPiezo);
            f0 = getf0BLfromConfiguration(app, Z1, Z2);

            numOffset = 5;
            linspaceArray = linspace(-numOffset, numOffset, numOffset*2 + 1);

            bandaDb = 6;
            bandaMax = -Inf;
            for i = linspaceArray
                [Zintrasduttore,FTTtrasduttore, spessorePiastra] = simulaTrasduttoreBandaLarga(freq_vector, f0, app.areaPiezo, app.v_ceramica, app.rho_ceramica, app.z_acqua, app.rho_acqua, Z1, Z2, app.C_0, spessore, app.h_33, i);
                [banda, index0, index1, freqCentroBanda] = calcolaBanda(freq_vector,FTTtrasduttore, bandaDb);
                if banda > bandaMax
                    bandaMax = banda;
                    maxOffset = i;
                    spessoreMax = spessorePiastra;
                end
                %plot(freq_vector, mag2db(abs(FTTtrasduttore)));
                %hold on;
            end

            app.SpessorepiastradiadattamentoEditField_2.Value = spessoreMax;
        end

        % Button pushed function: CalcolaspessoreformuleButton_2
        function CalcolaspessoreformuleButton_2Pushed(app, event)
            freq_vector = app.getFreqVector();
            spessore = app.SpessoremEditField.Value;
            isPerdite = app.PerditemeccanicheCheckBox_3_1_2.Value;
            Z1 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_2_1.Value, isPerdite, app.areaPiezo);
            Z2 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_2_2.Value, isPerdite, app.areaPiezo);
            f0 = getf0BLfromConfiguration(app, Z1, Z2);
            
            [Zintrasduttore,FTTtrasduttore, spessorePiastraIniziale] = simulaTrasduttoreBandaLarga(freq_vector, f0, app.areaPiezo, app.v_ceramica, app.rho_ceramica, app.z_acqua, app.rho_acqua, Z1, Z2, app.C_0, spessore, app.h_33, 0);
            app.SpessorepiastradiadattamentoEditField_2.Value = spessorePiastraIniziale; 
        end

        % Button pushed function: CalcolaspessoremiglioreButton_1
        function CalcolaspessoremiglioreButton_1Pushed(app, event)
            freq_vector = app.getFreqVector();
            spessore = app.SpessoremEditField.Value;
            isPerdite = app.PerditemeccanicheCheckBox_3_1_1.Value;
            Z1 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_1_1.Value, isPerdite, app.areaPiezo);
            Z2 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_1_2.Value, isPerdite, app.areaPiezo);
            f0 = getf0BLfromConfiguration(app, Z1, Z2);

            numOffset = 5;
            linspaceArray = linspace(-numOffset, numOffset, numOffset*2 + 1);

            bandaDb = 6;
            bandaMax = -Inf;
            for i = linspaceArray
                [Zintrasduttore,FTTtrasduttore, spessorePiastra] = simulaTrasduttoreBandaLarga(freq_vector, f0, app.areaPiezo, app.v_ceramica, app.rho_ceramica, app.z_acqua, app.rho_acqua, Z1, Z2, app.C_0, spessore, app.h_33, i);
                [banda, index0, index1, freqCentroBanda] = calcolaBanda(freq_vector,FTTtrasduttore, bandaDb);
                if banda > bandaMax
                    bandaMax = banda;
                    maxOffset = i;
                    spessoreMax = spessorePiastra;
                end
                %plot(freq_vector, mag2db(abs(FTTtrasduttore)));
                %hold on;
            end

            app.SpessorepiastradiadattamentoEditField_1.Value = spessoreMax;
        end

        % Callback function
        function SpessorepiastradiadattamentoEditField_1ValueChanged(app, event)

        end

        % Button pushed function: StudiaButton3_1
        function StudiaButton3_1Pushed(app, event)
            freq_vector = app.getFreqVector();
            spessore = app.SpessoremEditField.Value;

            isPerdite_1 = app.PerditemeccanicheCheckBox_3_1_1.Value;
            Z1_1 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_1_1.Value, isPerdite_1, app.areaPiezo);
            Z2_1 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_1_2.Value, isPerdite_1, app.areaPiezo);
            f0_1 = getf0BLfromConfiguration(app, Z1_1, Z2_1);
            spessore_edit_1 = app.SpessorepiastradiadattamentoEditField_1.Value;

            [Zintrasduttore_1,FTTtrasduttore_1, spessorePiastra_1] = simulaTrasduttoreBandaLargaForzaSpessore(freq_vector, f0_1, app.areaPiezo, app.v_ceramica, app.rho_ceramica, app.z_acqua, app.rho_acqua, Z1_1/app.areaPiezo, Z2_1/app.areaPiezo, app.C_0, spessore, app.h_33, spessore_edit_1);

            isPerdite_2 = app.PerditemeccanicheCheckBox_3_1_2.Value;
            Z1_2 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_2_1.Value, isPerdite_2, app.areaPiezo);
            Z2_2 = app.getZDaConfigurazione(app.TerminaleDropDown_3_1_2_2.Value, isPerdite_2, app.areaPiezo);
            f0_2 = getf0BLfromConfiguration(app, Z1_2, Z2_2);
            spessore_edit_2 = app.SpessorepiastradiadattamentoEditField_2.Value;

            [Zintrasduttore_2,FTTtrasduttore_2, spessorePiastra_2] = simulaTrasduttoreBandaLargaForzaSpessore(freq_vector, f0_2, app.areaPiezo, app.v_ceramica, app.rho_ceramica, app.z_acqua, app.rho_acqua, Z1_2/app.areaPiezo, Z2_2/app.areaPiezo, app.C_0, spessore, app.h_33, spessore_edit_2);

            app.stampaGraficiMultipli(app.UIAxes3_1_1, freq_vector./1e6, mag2db(abs(Zintrasduttore_1)), 'Configurazione 1',freq_vector./1e6, mag2db(abs(Zintrasduttore_2)), 'Configurazione 2','Impedenza', 'frequenza [MHz]', 'Zin [Ohm]')
            app.stampaGraficiMultipli(app.UIAxes3_1_2, freq_vector./1e6, mag2db(abs(FTTtrasduttore_1)), 'Configurazione 1',freq_vector./1e6, mag2db(abs(FTTtrasduttore_2)), 'Configurazione 2','Funzione di trasferimento trasmissione', 'frequenza [MHz]', 'FTT [dB]')
            app.stampaGraficiMultipli(app.UIAxes3_1_3, freq_vector./1e6, angle(Zintrasduttore_1), 'Configurazione 1',freq_vector./1e6, angle(Zintrasduttore_2), 'Configurazione 2','Parte immaginaria Impedenza', 'frequenza [MHz]', 'Img |Zin| [Ohm]')
        end

        % Button pushed function: StudiaButton4_1
        function StudiaButton4_1Pushed(app, event)

            freq_lavoro = app.FrequenzadilavoroKHzEditField.Value * 10^3;

            N_Campioni = app.NumeroCampioniEditField.Value;
            f_low = app.FattoremoltiplicativoinferioreEditField.Value * freq_lavoro;
            f_high = app.FattoremoltiplicativosuperioreEditField.Value * freq_lavoro;
            freq_vector = linspace(f_low, f_high, N_Campioni);

            spessore = app.SpessoremEditField.Value; 
            Z1 = app.z_aria * app.areaPiezo; 
            spessore_half = spessore/2; % valore di c visto che consideriamo metà del trasduttore nello studiO
            a_m1 = calcolaDimensioneMasse(freq_lavoro, app.v_titanio, app.rho_titanio, app.v_ceramica, app.rho_ceramica, spessore_half);
            a_m2 = a_m1; % visto che le due masse sono uguali

            Zacu_m1 = calcolaZacu(freq_vector, app.v_titanio, a_m1, app.rho_titanio, app.areaPiezo, Z1);
            Zacu_m2 = calcolaZacu(freq_vector, app.v_titanio, a_m2, app.rho_titanio, app.areaPiezo, Z1);

            C_0_half = app.areaPiezo / (app.beta_33_S * spessore_half);

            useSingleCeramic = app.ConfigurazioneDropDown.Value == "Una Ceramica";
            if (useSingleCeramic)
                C_O_langevin = app.C_0;
                spessore_langevin = spessore;
            else
                C_O_langevin = C_0_half;
                spessore_langevin = spessore_half;
            end

            Zin = calcolaZinZVector(app.Z_0_D, freq_vector, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, Zacu_m1, Zacu_m2, useSingleCeramic);

            [a_m1_new, Zin_new, Zacu_m1_new, Zacu_m2_new] = calcolaSpessoreRealeMasse(a_m1, Zin, freq_lavoro, freq_vector, app.v_titanio, app.rho_titanio, app.areaPiezo, Z1, app.Z_0_D, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, useSingleCeramic);
            a_m2_new = a_m1_new; % Sempre per simmetria si calcola solo uno dei valori
            FTT = calcolaFTTZVector(app.Z_0_D, freq_vector, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, Zacu_m1, Zacu_m2, useSingleCeramic);
            FTT_new = calcolaFTTZVector(app.Z_0_D, freq_vector, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, Zacu_m1_new, Zacu_m2_new, useSingleCeramic);
            FTT_i = FTT .* Zin;
            FTT_i_new = FTT_new .* Zin_new;

            app.stampaGraficiMultipli(app.UIAxes4_1, freq_vector./1e3, mag2db(abs(Zin)), 'Progetto Langevin',freq_vector./1e3, mag2db(abs(Zin_new)), 'Nuovo criterio ottimizzato per generatore di tensione','Impedenza', 'frequenza [KHz]', 'Zin [Ohm]')
            app.stampaGraficiMultipli(app.UIAxes4_2, freq_vector./1e3, mag2db(abs(FTT)), 'Progetto Langevin',freq_vector./1e3, mag2db(abs(FTT_new)), 'Nuovo criterio ottimizzato per generatore di tensione','Funzione di trasferimento trasmissione pilotata in tensione', 'frequenza [MKz]', 'FTT[V] [dB]')
            app.stampaGraficiMultipli(app.UIAxes4_3, freq_vector./1e3, mag2db(abs(FTT_i)), 'Progetto Langevin',freq_vector./1e3, mag2db(abs(FTT_i_new)), 'Nuovo criterio ottimizzato per generatore di tensione','Funzione di trasferimento trasmissione pilotata in corrente', 'frequenza [KHz]', 'FTT[I] [dB]')
    
        end

        % Button pushed function: StudiaButton5_1
        function StudiaButton5_1Pushed(app, event)
            freq_lavoro = app.FrequenzadilavoroKHzEditField_2.Value * 10^3;

            N_Campioni = app.NumeroCampioniEditField.Value;
            f_low = app.FattoremoltiplicativoinferioreEditField.Value * freq_lavoro;
            f_high = app.FattoremoltiplicativosuperioreEditField.Value * freq_lavoro;
            freq_vector = linspace(f_low, f_high, N_Campioni);

            spessore = app.SpessoremEditField.Value; 
            Z1 = app.z_aria * app.areaPiezo; 
            spessore_half = spessore/2; % valore di c visto che consideriamo metà del trasduttore nello studiO
            C_0_half = app.areaPiezo / (app.beta_33_S * spessore_half);


            useSingleCeramic = true;
            if (useSingleCeramic)
                C_O_langevin = app.C_0;
                spessore_langevin = spessore;
            else
                C_O_langevin = C_0_half;
                spessore_langevin = spessore_half;
            end


            a_m1 = calcolaDimensioneMasse(freq_lavoro, app.v_titanio, app.rho_titanio, app.v_ceramica, app.rho_ceramica, spessore_half);
            a_m2 = a_m1; % visto che le due masse sono uguali

            Zacu_m1 = calcolaZacu(freq_vector, app.v_titanio, a_m1, app.rho_titanio, app.areaPiezo, Z1);
            Zacu_m2 = calcolaZacu(freq_vector, app.v_titanio, a_m2, app.rho_titanio, app.areaPiezo, Z1);

            Zin = calcolaZinZVector(app.Z_0_D, freq_vector, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, Zacu_m1, Zacu_m2, useSingleCeramic);
            [a_m1_new, Zin_new, Zacu_m1_new, Zacu_m2_new] = calcolaSpessoreRealeMasse(a_m1, Zin, freq_lavoro, freq_vector, app.v_titanio, app.rho_titanio, app.areaPiezo, Z1, app.Z_0_D, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, useSingleCeramic);
            a_m2_new = a_m1_new; % Sempre per simmetria si calcola solo uno dei valori

            FTT = calcolaFTTZVector(app.Z_0_D, freq_vector, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, Zacu_m1, Zacu_m2, useSingleCeramic);
            FTT_new = calcolaFTTZVector(app.Z_0_D, freq_vector, app.v_ceramica, spessore_langevin, app.h_33, C_O_langevin, Zacu_m1_new, Zacu_m2_new, useSingleCeramic);

            v_massa = app.v_titanio;
            rho_massa = app.rho_titanio;
            lambda = v_massa / freq_lavoro;
            l_4 = lambda / 4; % criterio di progetto ottimale
            l_3 = l_4;
            w_exp = 0.0001; % lunghezza dell'esponenziale(Molto piccolo per simulare il gradino)
            N = app.NEditField.Value; 

            Z_massa4 = calcolaZacu(freq_vector, v_massa, l_4, rho_massa, app.areaPiezo, Z1);
            Z_concentratore = calcolaZConcentratore(v_massa, rho_massa, N, app.areaPiezo, w_exp, Z_massa4, freq_vector);
            
            [Z_massa3, M11, M12] = calcolaZacuVector(freq_vector, v_massa, l_4, rho_massa, app.areaPiezo, Z_concentratore);
            [Z_massa1] = calcolaZacu(freq_vector, v_massa, a_m1_new, rho_massa, app.areaPiezo, Z1);
            
            [Zin_finale, FTT_finale] = calcolaParametriFinali(app.Z_0_D, freq_vector, app.v_ceramica, spessore_half, app.h_33, C_0_half, Z_massa1, Z_massa3, Z1, M11, M12, true);

            app.stampaGrafico(app.UIAxes5_1, freq_vector./1e3, mag2db(abs(Zin_finale)), 'Impedenza', 'frequenza [KHz]', 'Zin [Ohm]');
            app.stampaGrafico(app.UIAxes5_2, freq_vector./1e3, mag2db(abs(FTT_finale)), 'Funzione di trasferimento trasmissione', 'frequenza [KHz]', 'FTT [dB]');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {818, 818};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SceltaPiezoelettricoPanel = uipanel(app.LeftPanel);
            app.SceltaPiezoelettricoPanel.Title = 'Scelta Piezoelettrico';
            app.PiezoelettricoDropDown = uidropdown(app.SceltaPiezoelettricoPanel);
            app.PiezoelettricoDropDown.ValueChangedFcn = createCallbackFcn(app, @PiezoelettricoDropDownValueChanged, true);
            app.CostanteElasticaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostanteElasticaLabel.Text = 'Costante elastica [N/m^2]:';
            app.CostantehVmLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantehVmLabel.Text = 'Costante h [V/m]:';
            app.CostantePiezoelettricaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantePiezoelettricaLabel.Text = 'Costante piezoelettrica [C/m^2] :';
            app.DensitaDiMassaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.DensitaDiMassaLabel.Text = 'Densità di massa [kg/m^3]:';
            app.CostanteElasticaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostanteElasticaValue.Text = '';
            app.CostantehValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantehValue.Text = '';
            app.CostantePiezoelettricaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantePiezoelettricaValue.Text = '';
            app.DensitaDiMassaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.DensitaDiMassaValue.Text = '';
            app.GeometriadellapiastraPanel = uipanel(app.LeftPanel);
            app.GeometriadellapiastraPanel.Title = 'Geometria della piastra';
            app.LunghezzamEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.LunghezzamEditFieldLabel.Text = 'Lunghezza [m]: ';
            app.LunghezzamEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.LunghezzamEditField.Limits = [0.0001 100];
            app.LunghezzamEditField.ValueChangedFcn = createCallbackFcn(app, @LunghezzamEditFieldValueChanged, true);
            app.LunghezzamEditField.Value = 0.01;
            app.LarghezzamEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.LarghezzamEditFieldLabel.Text = 'Larghezza [m]:';
            app.LarghezzamEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.LarghezzamEditField.Limits = [0.0001 100];
            app.LarghezzamEditField.ValueChangedFcn = createCallbackFcn(app, @LarghezzamEditFieldValueChanged, true);
            app.LarghezzamEditField.Value = 0.01;
            app.SpessoremLabel = uilabel(app.GeometriadellapiastraPanel);
            app.SpessoremLabel.Text = 'Spessore [m]';
            app.SpessoremEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.SpessoremEditField.Limits = [2e-05 50];
            app.SpessoremEditField.ValueChangedFcn = createCallbackFcn(app, @SpessoremEditFieldValueChanged, true);
            app.SpessoremEditField.Value = 0.002;
            app.ValoriDerivatiPanel = uipanel(app.LeftPanel);
            app.ValoriDerivatiPanel.Title = 'Valori Derivati';
            app.CostantebetaVmCLabel = uilabel(app.ValoriDerivatiPanel);
            app.CostantebetaVmCLabel.Text = 'Costante beta [V*m/C]:';
            app.Areapiezoelettricom2Label = uilabel(app.ValoriDerivatiPanel);
            app.Areapiezoelettricom2Label.Text = 'Area piezoelettrico [m^2]:';
            app.CapacitstaticaCVLabel = uilabel(app.ValoriDerivatiPanel);
            app.CapacitstaticaCVLabel.Text = 'Capacità statica [C/V]: ';
            app.VelocitdipropagazionemsLabel = uilabel(app.ValoriDerivatiPanel);
            app.VelocitdipropagazionemsLabel.Text = 'Velocità di propagazione [m/s]:';
            app.Impedenzaacusticapiezokgm2sLabel = uilabel(app.ValoriDerivatiPanel);
            app.Impedenzaacusticapiezokgm2sLabel.Text = 'Impedenza acustica piezo[kg/(m^2 * s)]:';
            app.ImpedenzaacusticadellelementoindirezionezkgsLabel = uilabel(app.ValoriDerivatiPanel);
            app.ImpedenzaacusticadellelementoindirezionezkgsLabel.Text = 'Impedenza acustica dell''elemento in direzione z [kg/s]:';
            app.FrequenzadirisonanzaHzLabel = uilabel(app.ValoriDerivatiPanel);
            app.FrequenzadirisonanzaHzLabel.Text = 'Frequenza di risonanza [Hz]: ';
            app.CostantebetaValue = uilabel(app.ValoriDerivatiPanel);
            app.CostantebetaValue.Text = '';
            app.AreapiezoValue = uilabel(app.ValoriDerivatiPanel);
            app.AreapiezoValue.Text = '';
            app.CapacitaStaticaValue = uilabel(app.ValoriDerivatiPanel);
            app.CapacitaStaticaValue.Text = '';
            app.VelocitaDiPropagazioneValue = uilabel(app.ValoriDerivatiPanel);
            app.VelocitaDiPropagazioneValue.Text = '';
            app.ImpedenzaAcusticaPiezoValue = uilabel(app.ValoriDerivatiPanel);
            app.ImpedenzaAcusticaPiezoValue.Text = '';
            app.ImpedenzaAcusticaDirezionezValue = uilabel(app.ValoriDerivatiPanel);
            app.ImpedenzaAcusticaDirezionezValue.Text = '';
            app.FrequenzaDiRisonanzaValue = uilabel(app.ValoriDerivatiPanel);
            app.FrequenzaDiRisonanzaValue.Text = '';
            app.IntervalloconsideratoPanel = uipanel(app.LeftPanel);
            app.IntervalloconsideratoPanel.Title = 'Intervallo considerato';
            app.NumeroCampioniEditFieldLabel = uilabel(app.IntervalloconsideratoPanel);
            app.NumeroCampioniEditFieldLabel.Text = 'Numero Campioni';
            app.NumeroCampioniEditField = uieditfield(app.IntervalloconsideratoPanel, 'numeric');
            app.NumeroCampioniEditField.Limits = [100 100000];
            app.NumeroCampioniEditField.Value = 1000;
            app.FattoremoltiplicativoinferioreEditFieldLabel = uilabel(app.IntervalloconsideratoPanel);
            app.FattoremoltiplicativoinferioreEditFieldLabel.Text = 'Fattore moltiplicativo inferiore';
            app.FattoremoltiplicativoinferioreEditField = uieditfield(app.IntervalloconsideratoPanel, 'numeric');
            app.FattoremoltiplicativoinferioreEditField.Limits = [-10 1];
            app.FattoremoltiplicativoinferioreEditField.Value = 0.5;
            app.FattoremoltiplicativosuperioreLabel = uilabel(app.IntervalloconsideratoPanel);
            app.FattoremoltiplicativosuperioreLabel.Text = 'Fattore moltiplicativo superiore';
            app.FattoremoltiplicativosuperioreEditField = uieditfield(app.IntervalloconsideratoPanel, 'numeric');
            app.FattoremoltiplicativosuperioreEditField.Limits = [1 10];
            app.FattoremoltiplicativosuperioreEditField.Value = 1.5;
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.UnaceramicaTab = uitab(app.TabGroup);
            app.UnaceramicaTab.Title = 'Una ceramica';
            app.TabGroup2 = uitabgroup(app.UnaceramicaTab);
            app.StudiosingolaconfigurazioneTab = uitab(app.TabGroup2);
            app.StudiosingolaconfigurazioneTab.Title = 'Studio singola configurazione';
            app.UIAxes1_1_1 = uiaxes(app.StudiosingolaconfigurazioneTab);
            title(app.UIAxes1_1_1, 'Title')
            xlabel(app.UIAxes1_1_1, 'X')
            ylabel(app.UIAxes1_1_1, 'Y')
            zlabel(app.UIAxes1_1_1, 'Z')
            app.UIAxes1_1_2 = uiaxes(app.StudiosingolaconfigurazioneTab);
            title(app.UIAxes1_1_2, 'Title')
            xlabel(app.UIAxes1_1_2, 'X')
            ylabel(app.UIAxes1_1_2, 'Y')
            zlabel(app.UIAxes1_1_2, 'Z')
            app.UIAxes1_1_3 = uiaxes(app.StudiosingolaconfigurazioneTab);
            title(app.UIAxes1_1_3, 'Title')
            xlabel(app.UIAxes1_1_3, 'X')
            ylabel(app.UIAxes1_1_3, 'Y')
            zlabel(app.UIAxes1_1_3, 'Z')
            app.CondizionialcontornoPanel = uipanel(app.StudiosingolaconfigurazioneTab);
            app.CondizionialcontornoPanel.Title = 'Condizioni al contorno';
            app.Terminale1Label = uilabel(app.CondizionialcontornoPanel);
            app.Terminale1Label.Text = 'Terminale 1';
            app.Terminale1DropDown = uidropdown(app.CondizionialcontornoPanel);
            app.Terminale1DropDown.Items = {'Aria', 'Acqua', 'Backing'};
            app.Terminale1DropDown.Value = 'Aria';
            app.Terminale2DropDownLabel = uilabel(app.CondizionialcontornoPanel);
            app.Terminale2DropDownLabel.Text = 'Terminale 2';
            app.Terminale2DropDown = uidropdown(app.CondizionialcontornoPanel);
            app.Terminale2DropDown.Items = {'Aria', 'Acqua', 'Backing'};
            app.Terminale2DropDown.Value = 'Aria';
            app.PerditemeccanicheCheckBox = uicheckbox(app.CondizionialcontornoPanel);
            app.PerditemeccanicheCheckBox.Text = ' Perdite meccaniche';
            app.StudiaButton1_1 = uibutton(app.CondizionialcontornoPanel, 'push');
            app.StudiaButton1_1.ButtonPushedFcn = createCallbackFcn(app, @StudiaButton1_1Pushed, true);
            app.StudiaButton1_1.Text = 'Studia';
            app.ConfrontodueconfigurazioniTab = uitab(app.TabGroup2);
            app.ConfrontodueconfigurazioniTab.Title = 'Confronto due configurazioni';
            app.UIAxes1_2_1 = uiaxes(app.ConfrontodueconfigurazioniTab);
            title(app.UIAxes1_2_1, 'Title')
            xlabel(app.UIAxes1_2_1, 'X')
            ylabel(app.UIAxes1_2_1, 'Y')
            zlabel(app.UIAxes1_2_1, 'Z')
            app.UIAxes1_2_2 = uiaxes(app.ConfrontodueconfigurazioniTab);
            title(app.UIAxes1_2_2, 'Title')
            xlabel(app.UIAxes1_2_2, 'X')
            ylabel(app.UIAxes1_2_2, 'Y')
            zlabel(app.UIAxes1_2_2, 'Z')
            app.UIAxes1_2_3 = uiaxes(app.ConfrontodueconfigurazioniTab);
            title(app.UIAxes1_2_3, 'Title')
            xlabel(app.UIAxes1_2_3, 'X')
            ylabel(app.UIAxes1_2_3, 'Y')
            zlabel(app.UIAxes1_2_3, 'Z')
            app.CondizionialcontornoPanel_2 = uipanel(app.ConfrontodueconfigurazioniTab);
            app.CondizionialcontornoPanel_2.Title = 'Condizioni al contorno';
            app.Configurazione1Label = uilabel(app.CondizionialcontornoPanel_2);
            app.Configurazione1Label.Text = 'Configurazione 1';
            app.Terminale1Label_2 = uilabel(app.CondizionialcontornoPanel_2);
            app.Terminale1Label_2.Text = 'Terminale 1';
            app.TerminaleDropDown_1_1_1 = uidropdown(app.CondizionialcontornoPanel_2);
            app.TerminaleDropDown_1_1_1.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_1_1_1.Value = 'Aria';
            app.Terminale2Label = uilabel(app.CondizionialcontornoPanel_2);
            app.Terminale2Label.Text = 'Terminale 2';
            app.TerminaleDropDown_1_1_2 = uidropdown(app.CondizionialcontornoPanel_2);
            app.TerminaleDropDown_1_1_2.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_1_1_2.Value = 'Aria';
            app.Configurazione2Label = uilabel(app.CondizionialcontornoPanel_2);
            app.Configurazione2Label.Text = 'Configurazione 2';
            app.PerditemeccanicheCheckBox_1_2_1 = uicheckbox(app.CondizionialcontornoPanel_2);
            app.PerditemeccanicheCheckBox_1_2_1.Text = ' Perdite meccaniche';
            app.Terminale1DropDown_2Label = uilabel(app.CondizionialcontornoPanel_2);
            app.Terminale1DropDown_2Label.Text = 'Terminale 1';
            app.TerminaleDropDown_1_2_1 = uidropdown(app.CondizionialcontornoPanel_2);
            app.TerminaleDropDown_1_2_1.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_1_2_1.Value = 'Aria';
            app.Terminale2DropDown_2Label = uilabel(app.CondizionialcontornoPanel_2);
            app.Terminale2DropDown_2Label.Text = 'Terminale 2';
            app.TerminaleDropDown_1_2_2 = uidropdown(app.CondizionialcontornoPanel_2);
            app.TerminaleDropDown_1_2_2.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_1_2_2.Value = 'Aria';
            app.PerditemeccanicheCheckBox_1_2_2 = uicheckbox(app.CondizionialcontornoPanel_2);
            app.PerditemeccanicheCheckBox_1_2_2.Text = ' Perdite meccaniche';
            app.StudiaButton1_2 = uibutton(app.CondizionialcontornoPanel_2, 'push');
            app.StudiaButton1_2.ButtonPushedFcn = createCallbackFcn(app, @StudiaButton1_2Pushed, true);
            app.StudiaButton1_2.Text = 'Studia';
            app.DueCeramicheTab = uitab(app.TabGroup);
            app.DueCeramicheTab.Title = 'Due ceramiche';
            app.UIAxes2_1_1 = uiaxes(app.DueCeramicheTab);
            title(app.UIAxes2_1_1, 'Title')
            xlabel(app.UIAxes2_1_1, 'X')
            ylabel(app.UIAxes2_1_1, 'Y')
            zlabel(app.UIAxes2_1_1, 'Z')
            app.UIAxes2_1_2 = uiaxes(app.DueCeramicheTab);
            title(app.UIAxes2_1_2, 'Title')
            xlabel(app.UIAxes2_1_2, 'X')
            ylabel(app.UIAxes2_1_2, 'Y')
            zlabel(app.UIAxes2_1_2, 'Z')
            app.UIAxes2_1_3 = uiaxes(app.DueCeramicheTab);
            title(app.UIAxes2_1_3, 'Title')
            xlabel(app.UIAxes2_1_3, 'X')
            ylabel(app.UIAxes2_1_3, 'Y')
            zlabel(app.UIAxes2_1_3, 'Z')
            app.CondizionialcontornoPanel_3 = uipanel(app.DueCeramicheTab);
            app.CondizionialcontornoPanel_3.Title = 'Condizioni al contorno';
            app.Terminale1DropDown_2Label_2 = uilabel(app.CondizionialcontornoPanel_3);
            app.Terminale1DropDown_2Label_2.Text = 'Terminale 1';
            app.TerminaleDropdown_2_1 = uidropdown(app.CondizionialcontornoPanel_3);
            app.TerminaleDropdown_2_1.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropdown_2_1.Value = 'Aria';
            app.Terminale2DropDown_2Label_2 = uilabel(app.CondizionialcontornoPanel_3);
            app.Terminale2DropDown_2Label_2.Text = 'Terminale 2';
            app.TerminaleDropdown_2_2 = uidropdown(app.CondizionialcontornoPanel_3);
            app.TerminaleDropdown_2_2.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropdown_2_2.Value = 'Aria';
            app.PerditemeccanicheCheckBox_2_1 = uicheckbox(app.CondizionialcontornoPanel_3);
            app.PerditemeccanicheCheckBox_2_1.Text = ' Perdite meccaniche';
            app.StudiaButton2_1 = uibutton(app.CondizionialcontornoPanel_3, 'push');
            app.StudiaButton2_1.ButtonPushedFcn = createCallbackFcn(app, @StudiaButton2_1Pushed, true);
            app.StudiaButton2_1.Text = 'Studia';
            app.TrasduttorebandalargaTab = uitab(app.TabGroup);
            app.TrasduttorebandalargaTab.Title = 'Trasduttore banda larga';
            app.UIAxes3_1_1 = uiaxes(app.TrasduttorebandalargaTab);
            title(app.UIAxes3_1_1, 'Title')
            xlabel(app.UIAxes3_1_1, 'X')
            ylabel(app.UIAxes3_1_1, 'Y')
            zlabel(app.UIAxes3_1_1, 'Z')
            app.UIAxes3_1_2 = uiaxes(app.TrasduttorebandalargaTab);
            title(app.UIAxes3_1_2, 'Title')
            xlabel(app.UIAxes3_1_2, 'X')
            ylabel(app.UIAxes3_1_2, 'Y')
            zlabel(app.UIAxes3_1_2, 'Z')
            app.UIAxes3_1_3 = uiaxes(app.TrasduttorebandalargaTab);
            title(app.UIAxes3_1_3, 'Title')
            xlabel(app.UIAxes3_1_3, 'X')
            ylabel(app.UIAxes3_1_3, 'Y')
            zlabel(app.UIAxes3_1_3, 'Z')
            app.CondizionialcontornoPanel_4 = uipanel(app.TrasduttorebandalargaTab);
            app.CondizionialcontornoPanel_4.Title = 'Condizioni al contorno';
            app.Configurazione1Label_2 = uilabel(app.CondizionialcontornoPanel_4);
            app.Configurazione1Label_2.Text = 'Configurazione 1';
            app.Terminale1Label_3 = uilabel(app.CondizionialcontornoPanel_4);
            app.Terminale1Label_3.Text = 'Terminale 1';
            app.TerminaleDropDown_3_1_1_1 = uidropdown(app.CondizionialcontornoPanel_4);
            app.TerminaleDropDown_3_1_1_1.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_3_1_1_1.Value = 'Aria';
            app.Terminale2Label_2 = uilabel(app.CondizionialcontornoPanel_4);
            app.Terminale2Label_2.Text = 'Terminale 2';
            app.TerminaleDropDown_3_1_1_2 = uidropdown(app.CondizionialcontornoPanel_4);
            app.TerminaleDropDown_3_1_1_2.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_3_1_1_2.Value = 'Aria';
            app.Configurazione2Label_2 = uilabel(app.CondizionialcontornoPanel_4);
            app.Configurazione2Label_2.Text = 'Configurazione 2';
            app.PerditemeccanicheCheckBox_3_1_1 = uicheckbox(app.CondizionialcontornoPanel_4);
            app.PerditemeccanicheCheckBox_3_1_1.Text = ' Perdite meccaniche';
            app.Terminale1DropDown_2Label_3 = uilabel(app.CondizionialcontornoPanel_4);
            app.Terminale1DropDown_2Label_3.Text = 'Terminale 1';
            app.TerminaleDropDown_3_1_2_1 = uidropdown(app.CondizionialcontornoPanel_4);
            app.TerminaleDropDown_3_1_2_1.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_3_1_2_1.Value = 'Aria';
            app.Terminale2DropDown_2Label_3 = uilabel(app.CondizionialcontornoPanel_4);
            app.Terminale2DropDown_2Label_3.Text = 'Terminale 2';
            app.TerminaleDropDown_3_1_2_2 = uidropdown(app.CondizionialcontornoPanel_4);
            app.TerminaleDropDown_3_1_2_2.Items = {'Aria', 'Acqua', 'Backing'};
            app.TerminaleDropDown_3_1_2_2.Value = 'Aria';
            app.PerditemeccanicheCheckBox_3_1_2 = uicheckbox(app.CondizionialcontornoPanel_4);
            app.PerditemeccanicheCheckBox_3_1_2.Text = ' Perdite meccaniche';
            app.StudiaButton3_1 = uibutton(app.CondizionialcontornoPanel_4, 'push');
            app.StudiaButton3_1.ButtonPushedFcn = createCallbackFcn(app, @StudiaButton3_1Pushed, true);
            app.StudiaButton3_1.Text = 'Studia';
            app.SpessorepiastradiadattamentoEditFieldLabel = uilabel(app.CondizionialcontornoPanel_4);
            app.SpessorepiastradiadattamentoEditFieldLabel.Text = 'Spessore piastra di adattamento';
            app.SpessorepiastradiadattamentoEditField_1 = uieditfield(app.CondizionialcontornoPanel_4, 'numeric');
            app.SpessorepiastradiadattamentoEditField_2Label = uilabel(app.CondizionialcontornoPanel_4);
            app.SpessorepiastradiadattamentoEditField_2Label.Text = 'Spessore piastra di adattamento';
            app.SpessorepiastradiadattamentoEditField_2 = uieditfield(app.CondizionialcontornoPanel_4, 'numeric');
            app.CalcolaspessoremiglioreButton_1 = uibutton(app.CondizionialcontornoPanel_4, 'push');
            app.CalcolaspessoremiglioreButton_1.ButtonPushedFcn = createCallbackFcn(app, @CalcolaspessoremiglioreButton_1Pushed, true);
            app.CalcolaspessoremiglioreButton_1.Text = 'Calcola spessore migliore ';
            app.CalcolaspessoremiglioreButton_2 = uibutton(app.CondizionialcontornoPanel_4, 'push');
            app.CalcolaspessoremiglioreButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcolaspessoremiglioreButton_2Pushed, true);
            app.CalcolaspessoremiglioreButton_2.Text = 'Calcola spessore migliore ';
            app.CalcolaspessoreformuleButton_1 = uibutton(app.CondizionialcontornoPanel_4, 'push');
            app.CalcolaspessoreformuleButton_1.ButtonPushedFcn = createCallbackFcn(app, @CalcolaspessoreformuleButton_1Pushed, true);
            app.CalcolaspessoreformuleButton_1.Text = 'Calcola spessore formule';
            app.CalcolaspessoreformuleButton_2 = uibutton(app.CondizionialcontornoPanel_4, 'push');
            app.CalcolaspessoreformuleButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcolaspessoreformuleButton_2Pushed, true);
            app.CalcolaspessoreformuleButton_2.Text = 'Calcola spessore formule';
            app.LangevinTab = uitab(app.TabGroup);
            app.LangevinTab.Title = 'Langevin';
            app.UIAxes4_1 = uiaxes(app.LangevinTab);
            title(app.UIAxes4_1, 'Title')
            xlabel(app.UIAxes4_1, 'X')
            ylabel(app.UIAxes4_1, 'Y')
            zlabel(app.UIAxes4_1, 'Z')
            app.UIAxes4_2 = uiaxes(app.LangevinTab);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_3 = uiaxes(app.LangevinTab);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            zlabel(app.UIAxes4_3, 'Z')
            app.Panel = uipanel(app.LangevinTab);
            app.ConfigurazioneDropDownLabel = uilabel(app.Panel);
            app.ConfigurazioneDropDownLabel.Text = 'Configurazione';
            app.ConfigurazioneDropDown = uidropdown(app.Panel);
            app.ConfigurazioneDropDown.Items = {'Una Ceramica', 'Due Ceramiche'};
            app.ConfigurazioneDropDown.Value = 'Una Ceramica';
            app.FrequenzadilavoroKHzEditFieldLabel = uilabel(app.Panel);
            app.FrequenzadilavoroKHzEditFieldLabel.Text = 'Frequenza di lavoro (KHz)';
            app.FrequenzadilavoroKHzEditField = uieditfield(app.Panel, 'numeric');
            app.FrequenzadilavoroKHzEditField.Limits = [1 Inf];
            app.FrequenzadilavoroKHzEditField.Value = 130;
            app.StudiaButton4_1 = uibutton(app.Panel, 'push');
            app.StudiaButton4_1.ButtonPushedFcn = createCallbackFcn(app, @StudiaButton4_1Pushed, true);
            app.StudiaButton4_1.Text = 'Studia';
            app.LangevinconconcentratoreTab = uitab(app.TabGroup);
            app.LangevinconconcentratoreTab.Title = 'Langevin con concentratore';
            app.UIAxes5_1 = uiaxes(app.LangevinconconcentratoreTab);
            title(app.UIAxes5_1, 'Title')
            xlabel(app.UIAxes5_1, 'X')
            ylabel(app.UIAxes5_1, 'Y')
            zlabel(app.UIAxes5_1, 'Z')
            app.UIAxes5_2 = uiaxes(app.LangevinconconcentratoreTab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.Panel_2 = uipanel(app.LangevinconconcentratoreTab);
            app.FrequenzadilavoroKHzEditField_2Label = uilabel(app.Panel_2);
            app.FrequenzadilavoroKHzEditField_2Label.Text = 'Frequenza di lavoro (KHz)';
            app.FrequenzadilavoroKHzEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.FrequenzadilavoroKHzEditField_2.Limits = [1 Inf];
            app.FrequenzadilavoroKHzEditField_2.Value = 130;
            app.StudiaButton5_1 = uibutton(app.Panel_2, 'push');
            app.StudiaButton5_1.ButtonPushedFcn = createCallbackFcn(app, @StudiaButton5_1Pushed, true);
            app.StudiaButton5_1.Text = 'Studia';
            app.NEditFieldLabel = uilabel(app.Panel_2);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Panel_2, 'numeric');
            app.NEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaccia_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end