classdef interfaccia_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        ValoriDerivatiPanel          matlab.ui.container.Panel
        GeometriadellapiastraPanel   matlab.ui.container.Panel
        SpessoremEditField           matlab.ui.control.NumericEditField
        SpessoremEditFieldLabel      matlab.ui.control.Label
        LarghezzamEditField          matlab.ui.control.NumericEditField
        LarghezzamEditFieldLabel     matlab.ui.control.Label
        LunghezzamEditField          matlab.ui.control.NumericEditField
        LunghezzamEditFieldLabel     matlab.ui.control.Label
        SceltaPiezoelettricoPanel    matlab.ui.container.Panel
        DensitaDiMassaValue          matlab.ui.control.Label
        CostantePiezoelettricaValue  matlab.ui.control.Label
        CostantehValue               matlab.ui.control.Label
        CostanteElasticaValue        matlab.ui.control.Label
        DensitaDiMassaLabel          matlab.ui.control.Label
        CostantePiezoelettricaLabel  matlab.ui.control.Label
        CostantehLabel               matlab.ui.control.Label
        CostanteElasticaLabel        matlab.ui.control.Label
        PiezoelettricoDropDown       matlab.ui.control.DropDown
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        UnaceramicaTab               matlab.ui.container.Tab
        DueCeramicheTab              matlab.ui.container.Tab
        TrasduttorebandalargaTab     matlab.ui.container.Tab
        LangevinTab                  matlab.ui.container.Tab
        LangevinconconcentratoreTab  matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        piezoTable
    end
    
    methods (Access = private)
        
        function aggiornaPiezo(app, piezoelettrico)
            app.CostanteElasticaValue.Text = strcat(num2str(app.piezoTable(56, piezoelettrico).(1), '%.3g'), ' [N/m^2]');
            app.CostantehValue.Text = strcat(num2str(app.piezoTable(23, piezoelettrico).(1), '%.3g'), ' [V/m]');
            app.CostantePiezoelettricaValue.Text = strcat(num2str(app.piezoTable(20, piezoelettrico).(1), '%.3g'), ' [C/m^2]');
            app.DensitaDiMassaValue.Text = strcat(num2str(app.piezoTable(33, piezoelettrico).(1), '%.3g'), ' [kg/m^3]');
        end

        function calcolaValoriDerivati(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            addpath('../condivise');
            app.piezoTable = readtable('Ferroperm MatData.xls');
            properties = app.piezoTable.Properties.VariableNames;
            propertiesConsidered = [properties(3:10), properties(15:end)];
            app.PiezoelettricoDropDown.Items = propertiesConsidered;
            aggiornaPiezo(app, 'Pz21');
        end

        % Value changed function: PiezoelettricoDropDown
        function PiezoelettricoDropDownValueChanged(app, event)
            value = app.PiezoelettricoDropDown.Value;
            aggiornaPiezo(app, value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {679, 679};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SceltaPiezoelettricoPanel = uipanel(app.LeftPanel);
            app.SceltaPiezoelettricoPanel.Title = 'Scelta Piezoelettrico';
            app.PiezoelettricoDropDown = uidropdown(app.SceltaPiezoelettricoPanel);
            app.PiezoelettricoDropDown.ValueChangedFcn = createCallbackFcn(app, @PiezoelettricoDropDownValueChanged, true);
            app.CostanteElasticaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostanteElasticaLabel.Text = 'Costante elastica:';
            app.CostantehLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantehLabel.Text = 'Costante h:';
            app.CostantePiezoelettricaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantePiezoelettricaLabel.Text = 'Costante piezoelettrica:';
            app.DensitaDiMassaLabel = uilabel(app.SceltaPiezoelettricoPanel);
            app.DensitaDiMassaLabel.Text = 'Densità di massa :';
            app.CostanteElasticaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostanteElasticaValue.Text = '';
            app.CostantehValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantehValue.Text = '';
            app.CostantePiezoelettricaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.CostantePiezoelettricaValue.Text = '';
            app.DensitaDiMassaValue = uilabel(app.SceltaPiezoelettricoPanel);
            app.DensitaDiMassaValue.Text = '';
            app.GeometriadellapiastraPanel = uipanel(app.LeftPanel);
            app.GeometriadellapiastraPanel.Title = 'Geometria della piastra';
            app.LunghezzamEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.LunghezzamEditFieldLabel.Text = 'Lunghezza [m]: ';
            app.LunghezzamEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.LunghezzamEditField.Limits = [0.0001 100];
            app.LunghezzamEditField.Value = 0.01;
            app.LarghezzamEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.LarghezzamEditFieldLabel.Text = 'Larghezza [m]:';
            app.LarghezzamEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.LarghezzamEditField.Limits = [0.0001 100];
            app.LarghezzamEditField.Value = 0.01;
            app.SpessoremEditFieldLabel = uilabel(app.GeometriadellapiastraPanel);
            app.SpessoremEditFieldLabel.Text = 'Spessore[m]';
            app.SpessoremEditField = uieditfield(app.GeometriadellapiastraPanel, 'numeric');
            app.SpessoremEditField.Limits = [2e-05 50];
            app.SpessoremEditField.Value = 0.002;
            app.ValoriDerivatiPanel = uipanel(app.LeftPanel);
            app.ValoriDerivatiPanel.Title = 'Valori Derivati';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.UnaceramicaTab = uitab(app.TabGroup);
            app.UnaceramicaTab.Title = 'Una ceramica';
            app.DueCeramicheTab = uitab(app.TabGroup);
            app.DueCeramicheTab.Title = 'Due Ceramiche';
            app.TrasduttorebandalargaTab = uitab(app.TabGroup);
            app.TrasduttorebandalargaTab.Title = 'Trasduttore banda larga';
            app.LangevinTab = uitab(app.TabGroup);
            app.LangevinTab.Title = 'Langevin';
            app.LangevinconconcentratoreTab = uitab(app.TabGroup);
            app.LangevinconconcentratoreTab.Title = 'Langevin con concentratore';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaccia_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end